classdef walabotUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HOMETab                         matlab.ui.container.Tab
        SensorTargetDetectionTab        matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        Panel_7                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        NumberoftargetstopresentLabel   matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        MovingTargetIndicatorLabel      matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        StopSave_STD                    matlab.ui.control.Button
        Start_STD                       matlab.ui.control.Button
        ApplyCalibrate_STD              matlab.ui.control.Button
        ConnectionStatusLamp_STD        matlab.ui.control.Lamp
        ConnectionStatusLamp_5Label     matlab.ui.control.Label
        None1MTILabel_3                 matlab.ui.control.Label
        Label_85                        matlab.ui.control.Label
        Thresold_STD                    matlab.ui.control.NumericEditField
        ThresoldLabel                   matlab.ui.control.Label
        Label_84                        matlab.ui.control.Label
        Label_83                        matlab.ui.control.Label
        Label_82                        matlab.ui.control.Label
        Phi_Res_STD                     matlab.ui.control.NumericEditField
        Theta_Res_STD                   matlab.ui.control.NumericEditField
        R_Res_STD                       matlab.ui.control.NumericEditField
        Label_81                        matlab.ui.control.Label
        Label_80                        matlab.ui.control.Label
        Label_79                        matlab.ui.control.Label
        Phi_STD                         matlab.ui.control.NumericEditField
        Theta_STD                       matlab.ui.control.NumericEditField
        R_Max_STD                       matlab.ui.control.NumericEditField
        R_Min_STD                       matlab.ui.control.NumericEditField
        PhiLabel_5                      matlab.ui.control.Label
        ThetaLabel_5                    matlab.ui.control.Label
        RcmLabel_5                      matlab.ui.control.Label
        RangeLabel_11                   matlab.ui.control.Label
        ValueLabel_10                   matlab.ui.control.Label
        ResolutionRangeLabel_4          matlab.ui.control.Label
        ResolutionLabel_4               matlab.ui.control.Label
        RangeLabel_10                   matlab.ui.control.Label
        MaxLabel_4                      matlab.ui.control.Label
        MinLabel_6                      matlab.ui.control.Label
        ValueLabel_9                    matlab.ui.control.Label
        UIAxes_RawImage                 matlab.ui.control.UIAxes
        UIAxesCartesianCoordinate       matlab.ui.control.UIAxes
        SensorBreathingTab              matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        AutoscalingrecommendedLabel     matlab.ui.control.Label
        StopSave_SB                     matlab.ui.control.Button
        Start_SB                        matlab.ui.control.Button
        ConnectionStatusLamp_SB         matlab.ui.control.Lamp
        ConnectionStatusLampLabel       matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        MinLabel_4                      matlab.ui.control.Label
        Phi_SB                          matlab.ui.control.NumericEditField
        Theta_SB                        matlab.ui.control.NumericEditField
        PhiLabel_2                      matlab.ui.control.Label
        ValueLabel_6                    matlab.ui.control.Label
        ThetaLabel_2                    matlab.ui.control.Label
        ApplyCalibrate_SB               matlab.ui.control.Button
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Phi_Res_SB                      matlab.ui.control.NumericEditField
        Theta_Res_SB                    matlab.ui.control.NumericEditField
        R_Res_SB                        matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        RangeLabel_6                    matlab.ui.control.Label
        AutoAdjustDisplayScaleCheckBox  matlab.ui.control.CheckBox
        Label_10                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        ValueLabel_4                    matlab.ui.control.Label
        ResolutionRangeLabel_2          matlab.ui.control.Label
        RangeLabel_4                    matlab.ui.control.Label
        MaxLabel_2                      matlab.ui.control.Label
        R_Max_SB                        matlab.ui.control.NumericEditField
        R_Min_SB                        matlab.ui.control.NumericEditField
        RcmLabel_2                      matlab.ui.control.Label
        UIAxesBreathingActivity         matlab.ui.control.UIAxes
        ImagingShortRangeTab            matlab.ui.container.Tab
        GridLayout7_3                   matlab.ui.container.GridLayout
        ValueMinMaxRangeResolutionRangeValueRangePanel_3  matlab.ui.container.Panel
        GridLayout13_5                  matlab.ui.container.GridLayout
        ApplyCalibrate_ISR              matlab.ui.control.Button
        StopSave_ISR                    matlab.ui.control.Button
        Start_ISR                       matlab.ui.control.Button
        ConnectionStatusLamp_ISR        matlab.ui.control.Lamp
        ConnectionStatusLamp_7Label     matlab.ui.control.Label
        Threshold_ISR                   matlab.ui.control.NumericEditField
        ThresholdEditField_5Label       matlab.ui.control.Label
        Label_52                        matlab.ui.control.Label
        Label_51                        matlab.ui.control.Label
        Label_50                        matlab.ui.control.Label
        Y_Res_ISR                       matlab.ui.control.NumericEditField
        X_Res_ISR                       matlab.ui.control.NumericEditField
        Y_Max_ISR                       matlab.ui.control.NumericEditField
        Y_Min_ISR                       matlab.ui.control.NumericEditField
        X_Max_ISR                       matlab.ui.control.NumericEditField
        X_Min_ISR                       matlab.ui.control.NumericEditField
        Z_Res_ISR                       matlab.ui.control.NumericEditField
        Z_Max_ISR                       matlab.ui.control.NumericEditField
        Z_Min_ISR                       matlab.ui.control.NumericEditField
        Label_49                        matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        YcmLabel_5                      matlab.ui.control.Label
        XcmLabel_5                      matlab.ui.control.Label
        ZcmLabel_5                      matlab.ui.control.Label
        UIAxesRawImageSlice             matlab.ui.control.UIAxes
        UIAxesInWallPipeDet             matlab.ui.control.UIAxes
        RawSignalsTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        Panel_8                         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ConnectionStatusLamp_RS         matlab.ui.control.Lamp
        ConnectionStatusLamp_6Label     matlab.ui.control.Label
        AntennaPairsPanel               matlab.ui.container.Panel
        Graph4DropDown                  matlab.ui.control.DropDown
        Graph4DropDownLabel             matlab.ui.control.Label
        Graph3DropDown                  matlab.ui.control.DropDown
        Graph3DropDownLabel             matlab.ui.control.Label
        Graph2DropDown                  matlab.ui.control.DropDown
        Graph2DropDownLabel             matlab.ui.control.Label
        Graph1DropDown                  matlab.ui.control.DropDown
        Graph1DropDownLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        StopSave_RS                     matlab.ui.control.StateButton
        Start_RS                        matlab.ui.control.StateButton
        Apply_RS                        matlab.ui.control.Button
        ProfileDropDown                 matlab.ui.control.DropDown
        ProfileDropDownLabel            matlab.ui.control.Label
        UIAxesAmplitudeTime             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        API % İşte burası app.API ifadesinin tanımlandığı yer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %==================== BAŞLANGIÇ DEĞERLERİ ====================
            % Sensor - Target Decection Tab
            app.R_Min_STD.Value = 10;
            app.R_Max_STD.Value = 100;
            app.R_Res_STD.Value = 2;
            app.Theta_STD.Value =20;
            app.Theta_Res_STD.Value = 10;
            app.Phi_STD.Value = 45;
            app.Phi_Res_STD.Value = 2;
            app.Thresold_STD.Value = 35;
            
            % Sensor - Breathing Tab
            app.R_Min_SB.Value = 20;
            app.R_Max_SB.Value = 80;
            app.R_Res_SB.Value = 0.2;
            app.Theta_SB.Value = 1;
            app.Theta_Res_SB.Value = 1;
            app.Phi_SB.Value = 1;
            app.Phi_Res_SB.Value = 1;

            % Imaging - Short Range
            app.Z_Min_ISR.Value = 3;
            app.Z_Max_ISR.Value = 8;
            app.Z_Res_ISR.Value = 0.5;
            app.X_Min_ISR.Value = -4;
            app.X_Max_ISR.Value = 4;
            app.X_Res_ISR.Value = 0.5;
            app.Y_Min_ISR.Value =-6;
            app.Y_Max_ISR.Value = 4;
            app.Y_Res_ISR.Value = 0.5;
            app.Threshold_ISR.Value = 35;

            % Tüm Connection Lamp'ları kırmızı başlasın
            app.ConnectionStatusLamp_STD.Color = 'red';
            app.ConnectionStatusLamp_SB.Color = 'red';
            app.ConnectionStatusLamp_ISR.Color = 'red';
            app.ConnectionStatusLamp_RS.Color = 'red';

            %=================== WALABOT API BAĞLANTISI ===================
            try
                % 1. dll yükle
                dllPath = 'C:\Program Files\Walabot\WalabotSDK\bin\WalabotAPI.NET.dll';
                NET.addAssembly(dllPath);
                %import WalabotAPI_NET.*;

                % 2. API Nesnesini oluştur
                app.API =  WalabotAPI_NET.WalabotAPI();

                % 3. Önce temizle (Walabot_Initialize teice hatasını önler)
                try
                    app.API.Clean();
                catch
                    % Eğer temizlenecek bir şey yoksa sesizce devam et
                end

                % 4. Bağlantı denemesi
                app.API.SetSettingsFolder('C:\ProgramData\Walabot\WalabotSDK');
                app.API.ConnectAny();

                % Cihaz bulundu - tüm lambaları yeşil yap
                app.ConnectionStatusLamp_STD.Color = 'green';
                app.ConnectionStatusLamp_SB.Color = 'green';
                app.ConnectionStatusLamp_ISR.Color = 'green';
                app.ConnectionStatusLamp_RS.Color = 'green';

            catch ME
                % no devices found hatası buraya düşer
                if contains(ME.message, 'No available devices') || contains(ME.message, 'Internal error')
                    fprintf('BİLGİ: Cihaz bağlı değil, simülasyon arayüzü aktif.\n');
                else
                    %Diğer teknik hataları gösterir
                    uialert(app.UIFigure, ['Sistem hatası: ', ME.message], 'Kritik Hata');
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.API.Clean();   %Program kapanırken cihazı serbest bırakır.
            catch
            end
                delete(app)
        end

        % Button pushed function: ApplyCalibrate_STD
        function ApplyCalibrate_STDButtonPushed(app, event)
            if strcmp(app.ConnectionStatusLamp_STD.Color, 'red')
                uialert(app.UIFigure, 'Cihaz bağlı değil!', 'Bağlantı Hatası');
                return;
            end

            try
                % Profil ayarla - SENSOR TARGET
                app.API.SetProfile(WalabotAPI_NET.SCAN_PROFILE.PROF_SENSOR);

                % Arena Ayarlarını Sırayla Gönderiyoruz
                app.API.SetArenaR(r_min_STD, r_max_STD, r_res_STD);
                app.API.SetArenaTheta(-theta_STD, theta_STD, theta_res_STD);
                app.API.SetArenaPhi(phi_STD, phi_res_STD);

                % Threshold ayarla
                app.API.SetThreshold(threshold_STD.Value);

                % MTI Filtresi
                app.API.SetDynamicImageFilter(WalabotAPI_NET.FILTER_TYPE.FILTER_TYPE_MTI);

                % Başlatma ve kalibrasyon
                app.API.Start();
                app.API.StartCalibration();

                uialert(app.UIFigure, 'Kalibrasyon Başlatıldı. Lütfen bekleyin...','Başarılı');

            catch ME
                % Cihaz yokken buraya düşmesi projenin yazılımsal olarak bittiğini gösterir
                uialert(app.UIFigure, ['Hata: ' ME. message, 'Donanım Hatası']);
            end
        end

        % Button pushed function: Start_STD
        function Start_STDButtonPushed(app, event)
            if strcmp(app.ConnectionStatusLamp_STD.Color, 'red')
                uialert(app.UIFigure,'Önce Apply-Calibrate yapın!','Uyarı');
                return
            end

            try
                % Kalibrasyon durumunu kontrol et
                app.API.Trigger();
                status = app.API.GetStatus();

                if status == WalabotAPI_NET.APP_STATUS.STATUS_CONFIGURED
                    uialert(app.UIFigure,'Kalibrasyon devam ediyor...', 'Bekleyin');
                    return
                end

                % Timer ile sürekli veri çekimi başlat
                app.SensorTimer = timer('ExecutionMode','fixedRate',...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~)updateSensorTargetDetection(app));
                start(app.SensorTimer);

            catch ME
                uialert(app.UIFigure, ['Hata: ', ME.message], 'Başlatma Hatası');
            end
        end

        % Veri Güncelleme Fonksiyonu
        function updateSensorTargetDetection(app)
            try
                app.API.Trigger();

                % Ham Görüntü Verisi Al (3D)
                [rasterImage, ~, ~, ~, ~] = app.API.GetRawImageSlice();

                % Target verilerini al
                targets = app.API.GetSensorTargets();
                numTargets = length(targets);

                %UIAxesRawImage'e raw image çiz (Phi/R)
                if ~isempty(rasterImage)
                    imagesc(app.UIAxes_RawImage, rasterImage);
                    colormap(app.UIAxes_RawImage, 'jet');
                    colorbar(app.UIAxes_RawImage);
                    title(app.UIAxes_RawImage,  'Raw Image - Angle(Phi)/Distance(R)');
                    xlabel(app.UIAxes_RawImage, 'Phi [°]');
                    ylabel(app.UIAxes_RawImage, 'R [cm]');
                end

                %UIAxesCartesianCoordinate'a Y/Z Cartesian koordinatları çiz
                cla(app.UIAxesCartesianCoordinate);

                if numTargets > 0
                    hold(app.UIAxesCartesianCoordinate,'on');

                    for i = 1:min(numTargets, 3)  % maxsimum 3 target göster
                        target = target(i);
                        xPos = target.xPosCm;
                        yPos = target.yPosCm;
                        zPos = target.zPosCm;
                        amplitude = target.amplitude;

                        % Y/Z düzleminde göster
                        scatter(app.UIAxesCartesianCoordinate, yPos, zPos, 100, 'filled', ...
                            'MarkerFaceColor', 'red', ...
                            'MarkerEdgeColor','black');
                        text(app.UIAxesCartesianCoordinate, yPos, zPos, sprintf(' T%d\n  X:%.1f Y:%.1f Z:%.1f\n  Amp:%.1f', ...
                            i, xPos, yPos, zPos, amplitude), ...
                            "FontSize", 10, 'Color', 'white', ...
                            'FontWeight','bold', 'BackgroundColor', [0 0 0 0.5]);
                    end

                    hold(app.UIAxesCartesianCoordinate, 'off');
                    xlabel(app.UIAxesCartesianCoordinate, 'Y [cm]');
                    ylabel(app.UIAxesCartesianCoordinate, 'Z [cm]');
                    title(app.UIAxesCartesianCoordinate, sprintf('Y/Z [Cartesian] - %d Targets', numTargets));
                    grid(app.UIAxesCartesianCoordinate,'on');
                    axis(app.UIAxesCartesianCoordinate, 'equal');
                else
                    title(app.UIAxesCartesianCoordinate, 'Y/Z Cartesian - No Targets');
                    xlabel(app.UIAxesCartesianCoordinate, 'Y [cm]');
                    ylabel(app.UIAxesCartesianCoordinate, 'Z [cm]');
                    grid(app.UIAxesCartesianCoordinate,'on');
                end

            catch ME
                fprintf('Güncelleme Hatası: %s\n', ME.message);
            end
        end

        % Button pushed function: StopSave_STD
        function StopSave_STDPushed(app, event)
            try
                if ~isampty(app.SensorTimer) && isvalid(app.SensorTimer)
                    stop(app.SensorTimer);
                    delete(app.app.SensorTimer);
                end

                app.API.Stop();
                app.API.Disconnet();

                uialer(app.UIFigure, ['Veri kaydedildi: ', filename], 'Başarılı');
            
            catch ME
                fprintf('Durdurma Hatası: %s\n', ME.message);
            end
        end

        % Button pushed function: ApplyCalibrate_SB
        function ApplyCalibrate_SBButtonPushed(app, event)
            if strcmp(app.ConnectionStatusLamp_SB.Color, 'red')
                uialert(app.UIFigure, 'Cihaz bağlı değil!', 'Bağlantı Hatası');
                return;
            end

            try
                % Profil - SENSOR (Breathing için)
                app.API.SetProfile(WalabotAPI_NET.SCAN_PROFILE.PROF_SENSOR);

                % Arena ayarları
                app.API.SetArenaR(r_min_SB, r_max_SB, r_res_SB);
                app.API.SetArenaTheta(-theta_SB, theta_SB, theta_res_SB);
                app.API.SetArenaTheta(-phi_SB, phi_SB, phi_res_SB);

                % MTI Filtre (hareketli hedefler için)
                app.API.SetDynamicImageFilter(WalabotAPI_NET.FILTER_TYPE.FILTER_TYPE_MTI);

                % Başlat
                app.API.Start();
                app.API.StartCalibration();

                uialert(app.UIFigure, 'Kalibrasyon başlaltıldı...', 'Başarılı');

            catch ME
                uialert(app.UIFigure, ['Hata: ', ME.message], 'Donanım Hatası');
            end
        end

        % Button pushed function: Start_SB
        function Start_SBButtonPushed(app, event)
            % Bağlantı Kontrolü
            if strcmp(app.ConnectionStatusLamp_SB.Color, 'red')
                uialert(app.UIFigure, 'Önce Apply-Calibrate yapın!', 'Uyarı');
                return
            end

            try
                % Kalibrasyon Kontrolü
                app.API.Trigger();
                status = app.API.GetStatus();

                if status == WalabotAPI_NET.APP_STATUS.STATUS_CALIBRATING
                    uialert(app.UIFigure, 'Kalibrasyon devam ediyor...', 'Bekleyin');
                    return
                end

                % Breathing verisi için buffer
                app.BreathingData = [];
                app.TimeData = [];
                app.BreathingStartTime = tic;

                % Timer başlat
                app.BreathingTimer = timer('ExecutionMode', 'fixedRate', ...
                                  'Period', 0.05, ... % 20 Hz
                                  'TimerFcn', @(~,~)updateBreathing(app));
                start(app.BreathingTimer);

                uialert(app.UIFigure, 'Breathing monitoring başlatıldı.', 'Başarılı');
                
            catch ME
                uialert(app.UIFigure, 'Hata: ', ME.message, 'Başlangıç Hatası');
            end
        end

        % Breathing verisi güncelleme
        function updateBreathing(app)
            try
                app.API.Trigger();

                % Breathing energy al
                energy = app.API.GetBrethingEnergy();

                % Zaman damgası
                currentTime = toc(app.BreathingStartTime);

                % Verileri biriktir (son 10 saniye)
                app.BreathingData = [app.BreathingData, energy];
                app.TimeData = [app.TimeData, currentTime];

                % 10 saniyedeki eski verileri sil
                keepIdx = app.TimeData > (currentTime - 10);
                app.BreathingData = app.BreathingData(keepIdx);
                app.TimedATA = APP.TimeData(keepIdx);

                % Grafik çiz
                plot(app.UIAxesBreathingActivity, app.TimeData, app.BreathingData, 'b-', 'LineWidth', 2);
                xlabel(app.UIAxesBreathingActivity,'Time[s]');
                ylabel(app.UIAxesBreathingActivity, 'Breathing Energy');
                title(app.UIAxesBreathingActivity, 'Breathing Activity');
                grid(app.UIAxesBreathingActivity, 'on');

                % Auto-scale ayarı
                if app.AutoAdjustDisplayScaleCheckBox.Value
                    ylim(app.UIAxesBreathingActivity, 'auto');
                else
                    sliderValue = app.Slider.Value;
                    ylim(app.UIAxesBreathingActivity, [0, sliderValue]);
                end

            catch ME
                fprintf('Breathing güncelleme hatası: %s\n', ME.message);
            end                      
        end

        % Button pushed function: StopSave_SB
        function StopSave_SBButtonPushed(app, event)
            try
                if ~isempty(app.BreathingTimer) && isvalid(app.BreathingTimer)
                    stop(app.BreathingTimer);
                    delete(app.BreathingTimer);
                end
        
                % Veriyi kaydet
                filename = sprintf('breathing_data_%s.mat', datestr(now, 'yyyymmdd_HHMMSS'));
                breathingData = app.BreathingData;
                timeData = app.TimeData;
                save(filename, 'breathingData', 'timeData');
        
                app.API.Stop();
        
                uialert(app.UIFigure, ['Veri kaydedildi: ', filename], 'Başarılı');
            catch ME
                fprintf('Durdurma hatası: %s\n', ME.message);
            end      
        end

        % Button pushed function: ApplyCalibrate_ISR
        function ApplyCalibrate_ISRButtonPushed(app, event)
            % Bağlantı kontrolü
            if strcmp(app.ConnectionStatusLamp_ISR.Color, 'red')
                uialert(app.UIFigure, 'Cihaz bağlı değil! Önce Cihazı bağlayın.', 'Bağlantı Hatası');
                return
            end

            try
                % Profil - SHORT_RANGE_IMAGING
                app.API.SetProfile(WalabotAPI_NET.SCAN_PROFILE.PROF_SHORT_RANGE_IMAGING);

                % 3D Arena Ayarları
                app.API.SetArenaR(app.Z_Min_ISR.Value, app.Z_Max_ISR.Value, app.Z_Res_ISR.Value);
                app.API.SetArenaX(app.X_Min_ISR.Value, app.X_Max_ISR.Value, app.X_Res_ISR.Value);
                app.API.SetArenaY(app.Y_Min_ISR.Value, app.Y_Max_ISR.Value, app.Y_Res_ISR.Value);

                % Threshold
                app.API.SetDynamicImageFilter(WalabotAPI_NET.FILTER_TYPE.FILTER_TYPE_NONE);

                % Başlat
                app.API.Start();
                app.API.StartCalibration();

                uialert(app.UIFigure, 'Kalibrasyon Başlatıldı... ', 'Başarılı');

            catch ME
                uialert(app.UIFigure, ['Hata: ', ME.message], 'Donanım Hatası');
            end
        end

        % Button pushed function: Start_ISR
        function Start_ISRButtonPushed(app, event)
            % Bağlantı Kontrolü
            if strcmp(app.ConnectionStatusLamp_ISR.Color, 'red')
                uialert(app.UIFigure, 'Önce Apply-Calibrate yapın!', 'Uyarı');
                return
            end

            try
                % Kalibrasyon kontrolü
                app.API.Trigger();
                status = app.API.GetStatus();

                if status == WalabotAPI_NET.APP_STATUS.STATUS_CALIBRATING
                    uialert(app.UIFigure, 'Kalibrasyon devam ediyor...', 'Bekleyin');
                    return
                end

                % Timer başlat
                app.ImagingTimer = timer('ExecutionMode','fixedRate', ...
                    'Period', 0.1,...
                    'TimerFcn', @(~,~)updateImaging(app));
                start(app.ImagingTimer);

                uialert(app.UIFigure, 'Imaging başlatıldı.', 'Başarılı');

            catch ME
                uialert(app.UIFigure, ['Hata: ', ME.message], 'Başlatma Hatası');
            end
        end

        % Imaging güncelleme fonksiyonu
        function updateImaging(app)
            try
                app.API.Trigger();

                % 3D raw image al
                [rasterImage, sizeX, sizeY, sizeZ, ~] = app.API.GetRawImage();

                % X/Y kesiti (Z ortası)
                if ~isempty(rasterImage) && sizeZ >0
                    midZ = round(sizeZ /2);
                    xySlice = squeeze(rasterImage(:, :, midZ));

                    % UIAxesRawImageSlice'a X/Y görüntüsünü çiz
                    imagesc(app.UIAxesRawImageSlice, xySlice);
                    colormap(app.UIAxesRawImageSlice, 'jet');
                    colorbar(app.UIAxesRawImageSlice);
                    xlabel(app.UIAxesRawImageSlice, 'X [cm]');
                    ylabel(app.UIAxesRawImageSlice, 'Y [cm]');
                    title(app.UIAxesRawImageSlice, sprintf('Raw Image Slice - X/Y (Z=%.1f cm)', midZ));

                    % UIAxesInWallPipeDet'e boru dedektörü
                    detectedObjects = xySlice > app.Threshold_ISR.Value;

                    imagesc(app.UIAxesInWallPipeDet, detectedObjects);
                    colormap(app.UIAxesInWallPipeDet, [0 0 0; 1 0 0]);
                    title(app.UIAxesInWallPipeDet, 'In-Wall Pipe Detector');
                    xlabel(app.UIAxesInWallPipeDet, 'X [cm]');
                    ylabel(app.UIAxesInWallPipeDet, 'Y [cm]');
                end

            catch ME
                fprintf('Imaging güncelleme hatası: %s\n', ME.message);
            end
        end

        % Value changed function: AutoAdjustDisplayScaleCheckBox
        function AutoAdjustDisplayScaleCheckBoxValueChanged(app, event)
            if app.AutoAdjustDisplayScaleCheckBox.Value
                % Auto-scaling aktif
                app.Slider.Value = 25;
                app.Slider.Limits = [0 100];
                app.Slider.Enable = 'off';
                app.Slider.Visible = 'on';   % Göster
            else
                % Manuel scaling
                app.Slider.Value = 50;
                app.Slider.Limits = [0 100];
                app.Slider.Enable = 'on';
                app.Slider.Visible = 'on';   % Göster
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.SensorTargetDetectionTab = uitab(app.TabGroup);
            app.SensorTargetDetectionTab.Title = 'Sensor - Target Detection';
            app.GridLayout9 = uigridlayout(app.SensorTargetDetectionTab);
            app.UIAxesCartesianCoordinate = uiaxes(app.GridLayout9);
            title(app.UIAxesCartesianCoordinate, 'Y/Z [Cartesian Coordinates]')
            app.UIAxesCartesianCoordinate.XTick = [];
            app.UIAxesCartesianCoordinate.YTick = [];
            app.UIAxes_RawImage = uiaxes(app.GridLayout9);
            title(app.UIAxes_RawImage, 'Raw Image - Angle(Phi)/ Distance(R)')
            app.UIAxes_RawImage.XTick = [];
            app.UIAxes_RawImage.YTick = [];
            app.Panel_7 = uipanel(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.Panel_7);
            app.ValueLabel_9 = uilabel(app.GridLayout10);
            app.ValueLabel_9.Text = 'Value';
            app.MinLabel_6 = uilabel(app.GridLayout10);
            app.MinLabel_6.Text = 'Min';
            app.MaxLabel_4 = uilabel(app.GridLayout10);
            app.MaxLabel_4.Text = 'Max';
            app.RangeLabel_10 = uilabel(app.GridLayout10);
            app.RangeLabel_10.Text = 'Range';
            app.ResolutionLabel_4 = uilabel(app.GridLayout10);
            app.ResolutionLabel_4.Text = 'Resolution';
            app.ResolutionRangeLabel_4 = uilabel(app.GridLayout10);
            app.ResolutionRangeLabel_4.Text = 'Resolution Range';
            app.ValueLabel_10 = uilabel(app.GridLayout10);
            app.ValueLabel_10.Text = 'Value';
            app.RangeLabel_11 = uilabel(app.GridLayout10);
            app.RangeLabel_11.Text = 'Range';
            app.RcmLabel_5 = uilabel(app.GridLayout10);
            app.RcmLabel_5.Text = 'R [cm]:';
            app.ThetaLabel_5 = uilabel(app.GridLayout10);
            app.ThetaLabel_5.Text = 'Theta [°]:';
            app.PhiLabel_5 = uilabel(app.GridLayout10);
            app.PhiLabel_5.Text = 'Phi [°]:';
            app.R_Min_STD = uieditfield(app.GridLayout10, 'numeric');
            app.R_Max_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Theta_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Phi_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Label_79 = uilabel(app.GridLayout10);
            app.Label_79.Text = '[1 - 600]';
            app.Label_80 = uilabel(app.GridLayout10);
            app.Label_80.Text = '[1 - 90]';
            app.Label_81 = uilabel(app.GridLayout10);
            app.Label_81.Text = '[1 - 90]';
            app.R_Res_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Theta_Res_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Phi_Res_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Label_82 = uilabel(app.GridLayout10);
            app.Label_82.Text = '[0.1 - 10]';
            app.Label_83 = uilabel(app.GridLayout10);
            app.Label_83.Text = '[1 - 10]';
            app.Label_84 = uilabel(app.GridLayout10);
            app.Label_84.Text = '[1 - 10]';
            app.ThresoldLabel = uilabel(app.GridLayout10);
            app.ThresoldLabel.Text = 'Thresold:';
            app.Thresold_STD = uieditfield(app.GridLayout10, 'numeric');
            app.Label_85 = uilabel(app.GridLayout10);
            app.Label_85.Text = '[0 - 100]';
            app.None1MTILabel_3 = uilabel(app.GridLayout10);
            app.None1MTILabel_3.Text = '[0-None 1-MTI]';
            app.ConnectionStatusLamp_5Label = uilabel(app.GridLayout10);
            app.ConnectionStatusLamp_5Label.Text = 'Connection Status:';
            app.ConnectionStatusLamp_STD = uilamp(app.GridLayout10);
            app.ApplyCalibrate_STD = uibutton(app.GridLayout10, 'push');
            app.ApplyCalibrate_STD.ButtonPushedFcn = createCallbackFcn(app, @ApplyCalibrate_STDButtonPushed, true);
            app.ApplyCalibrate_STD.Text = 'Apply-Calibrate';
            app.Start_STD = uibutton(app.GridLayout10, 'push');
            app.Start_STD.ButtonPushedFcn = createCallbackFcn(app, @Start_STDButtonPushed, true);
            app.Start_STD.Text = 'Start';
            app.StopSave_STD = uibutton(app.GridLayout10, 'push');
            app.StopSave_STD.ButtonPushedFcn = createCallbackFcn(app, @StopSave_STDPushed, true);
            app.StopSave_STD.Text = 'Stop-Save';
            app.CheckBox = uicheckbox(app.GridLayout10);
            app.CheckBox.Text = '';
            app.MovingTargetIndicatorLabel = uilabel(app.GridLayout10);
            app.MovingTargetIndicatorLabel.Text = 'Moving Target Indicator:';
            app.ButtonGroup = uibuttongroup(app.GridLayout10);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '3';
            app.NumberoftargetstopresentLabel = uilabel(app.GridLayout10);
            app.NumberoftargetstopresentLabel.Text = 'Number of targets to present:';
            app.SensorBreathingTab = uitab(app.TabGroup);
            app.SensorBreathingTab.Title = 'Sensor - Breathing';
            app.GridLayout3 = uigridlayout(app.SensorBreathingTab);
            app.UIAxesBreathingActivity = uiaxes(app.GridLayout3);
            title(app.UIAxesBreathingActivity, 'Breathing Activity')
            app.UIAxesBreathingActivity.XTick = [];
            app.UIAxesBreathingActivity.YTick = [];
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.RcmLabel_2 = uilabel(app.GridLayout8);
            app.RcmLabel_2.Text = 'R [cm]:';
            app.R_Min_SB = uieditfield(app.GridLayout8, 'numeric');
            app.R_Max_SB = uieditfield(app.GridLayout8, 'numeric');
            app.MaxLabel_2 = uilabel(app.GridLayout8);
            app.MaxLabel_2.Text = 'Max';
            app.RangeLabel_4 = uilabel(app.GridLayout8);
            app.RangeLabel_4.Text = 'Range';
            app.ResolutionRangeLabel_2 = uilabel(app.GridLayout8);
            app.ResolutionRangeLabel_2.Text = 'Resolution Range';
            app.ValueLabel_4 = uilabel(app.GridLayout8);
            app.ValueLabel_4.Text = 'Value';
            app.Label_8 = uilabel(app.GridLayout8);
            app.Label_8.Text = '[1 - 1000]';
            app.Label_10 = uilabel(app.GridLayout8);
            app.Label_10.Text = '[1 - 10]';
            app.AutoAdjustDisplayScaleCheckBox = uicheckbox(app.GridLayout8);
            app.AutoAdjustDisplayScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoAdjustDisplayScaleCheckBoxValueChanged, true);
            app.AutoAdjustDisplayScaleCheckBox.Text = 'Auto Adjust Display Scale';
            app.RangeLabel_6 = uilabel(app.GridLayout8);
            app.RangeLabel_6.Text = 'Range';
            app.Slider = uislider(app.GridLayout8);
            app.Slider.MajorTicks = [];
            app.Slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.minLabel = uilabel(app.GridLayout8);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.GridLayout8);
            app.maxLabel.Text = 'max';
            app.R_Res_SB = uieditfield(app.GridLayout8, 'numeric');
            app.Theta_Res_SB = uieditfield(app.GridLayout8, 'numeric');
            app.Phi_Res_SB = uieditfield(app.GridLayout8, 'numeric');
            app.Label_13 = uilabel(app.GridLayout8);
            app.Label_13.Text = '[0.1 - 10]';
            app.Label_14 = uilabel(app.GridLayout8);
            app.Label_14.Text = '[1 - 10]';
            app.Label_15 = uilabel(app.GridLayout8);
            app.Label_15.Text = '[1 - 10]';
            app.ApplyCalibrate_SB = uibutton(app.GridLayout8, 'push');
            app.ApplyCalibrate_SB.ButtonPushedFcn = createCallbackFcn(app, @ApplyCalibrate_SBButtonPushed, true);
            app.ApplyCalibrate_SB.Text = 'Apply-Calibrate';
            app.ThetaLabel_2 = uilabel(app.GridLayout8);
            app.ThetaLabel_2.Text = 'Theta [°]:';
            app.ValueLabel_6 = uilabel(app.GridLayout8);
            app.ValueLabel_6.Text = 'Value';
            app.PhiLabel_2 = uilabel(app.GridLayout8);
            app.PhiLabel_2.Text = 'Phi [°]:';
            app.Theta_SB = uieditfield(app.GridLayout8, 'numeric');
            app.Phi_SB = uieditfield(app.GridLayout8, 'numeric');
            app.MinLabel_4 = uilabel(app.GridLayout8);
            app.MinLabel_4.Text = 'Min';
            app.Label_17 = uilabel(app.GridLayout8);
            app.Label_17.Text = '[1 - 10]';
            app.ConnectionStatusLampLabel = uilabel(app.GridLayout8);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp_SB = uilamp(app.GridLayout8);
            app.Start_SB = uibutton(app.GridLayout8, 'push');
            app.Start_SB.ButtonPushedFcn = createCallbackFcn(app, @Start_SBButtonPushed, true);
            app.Start_SB.Text = 'Start';
            app.StopSave_SB = uibutton(app.GridLayout8, 'push');
            app.StopSave_SB.ButtonPushedFcn = createCallbackFcn(app, @StopSave_SBButtonPushed, true);
            app.StopSave_SB.Text = 'Stop-Save';
            app.AutoscalingrecommendedLabel = uilabel(app.GridLayout8);
            app.AutoscalingrecommendedLabel.Text = '✔ - Auto scaling ( recommended)';
            app.ImagingShortRangeTab = uitab(app.TabGroup);
            app.ImagingShortRangeTab.Title = 'Imaging - Short Range';
            app.GridLayout7_3 = uigridlayout(app.ImagingShortRangeTab);
            app.UIAxesInWallPipeDet = uiaxes(app.GridLayout7_3);
            title(app.UIAxesInWallPipeDet, 'In-Wall Pipe Detector')
            app.UIAxesInWallPipeDet.XTick = [];
            app.UIAxesInWallPipeDet.YTick = [];
            app.UIAxesRawImageSlice = uiaxes(app.GridLayout7_3);
            title(app.UIAxesRawImageSlice, 'Raw Image Slice - X/Y')
            app.UIAxesRawImageSlice.XTick = [];
            app.UIAxesRawImageSlice.YTick = [];
            app.ValueMinMaxRangeResolutionRangeValueRangePanel_3 = uipanel(app.GridLayout7_3);
            app.ValueMinMaxRangeResolutionRangeValueRangePanel_3.Title = '  Value       Min                    Max                 Range     Resolution       Range                 Value                                     Range';
            app.GridLayout13_5 = uigridlayout(app.ValueMinMaxRangeResolutionRangeValueRangePanel_3);
            app.ZcmLabel_5 = uilabel(app.GridLayout13_5);
            app.ZcmLabel_5.Text = 'Z [cm]:';
            app.XcmLabel_5 = uilabel(app.GridLayout13_5);
            app.XcmLabel_5.Text = 'X [cm]:';
            app.YcmLabel_5 = uilabel(app.GridLayout13_5);
            app.YcmLabel_5.Text = 'Y [cm]:';
            app.Label_46 = uilabel(app.GridLayout13_5);
            app.Label_46.Text = '[1 - 20]';
            app.Label_47 = uilabel(app.GridLayout13_5);
            app.Label_47.Text = '[±10]';
            app.Label_48 = uilabel(app.GridLayout13_5);
            app.Label_48.Text = '[0.1 - 10]';
            app.Label_49 = uilabel(app.GridLayout13_5);
            app.Label_49.Text = '[0 - 100]';
            app.Z_Min_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.Z_Max_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.Z_Res_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.X_Min_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.X_Max_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.Y_Min_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.Y_Max_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.X_Res_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.Y_Res_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.Label_50 = uilabel(app.GridLayout13_5);
            app.Label_50.Text = '[±10]';
            app.Label_51 = uilabel(app.GridLayout13_5);
            app.Label_51.Text = '[0.1 - 10]';
            app.Label_52 = uilabel(app.GridLayout13_5);
            app.Label_52.Text = '[0.1 - 10]';
            app.ThresholdEditField_5Label = uilabel(app.GridLayout13_5);
            app.ThresholdEditField_5Label.Text = 'Threshold:';
            app.Threshold_ISR = uieditfield(app.GridLayout13_5, 'numeric');
            app.ConnectionStatusLamp_7Label = uilabel(app.GridLayout13_5);
            app.ConnectionStatusLamp_7Label.Text = 'Connection Status:';
            app.ConnectionStatusLamp_ISR = uilamp(app.GridLayout13_5);
            app.Start_ISR = uibutton(app.GridLayout13_5, 'push');
            app.Start_ISR.ButtonPushedFcn = createCallbackFcn(app, @Start_ISRButtonPushed, true);
            app.Start_ISR.Text = 'Start';
            app.StopSave_ISR = uibutton(app.GridLayout13_5, 'push');
            app.StopSave_ISR.Text = 'Stop-Save';
            app.ApplyCalibrate_ISR = uibutton(app.GridLayout13_5, 'push');
            app.ApplyCalibrate_ISR.ButtonPushedFcn = createCallbackFcn(app, @ApplyCalibrate_ISRButtonPushed, true);
            app.ApplyCalibrate_ISR.Text = 'Apply-Calibrate';
            app.RawSignalsTab = uitab(app.TabGroup);
            app.RawSignalsTab.Title = 'Raw Signals';
            app.GridLayout4 = uigridlayout(app.RawSignalsTab);
            app.UIAxesAmplitudeTime = uiaxes(app.GridLayout4);
            title(app.UIAxesAmplitudeTime, 'Amplitude / Time')
            app.UIAxesAmplitudeTime.XTick = [];
            app.UIAxesAmplitudeTime.YTick = [];
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Panel = uipanel(app.GridLayout5);
            app.ProfileDropDownLabel = uilabel(app.Panel);
            app.ProfileDropDownLabel.Text = 'Profile:';
            app.ProfileDropDown = uidropdown(app.Panel);
            app.ProfileDropDown.Items = {'Imaging', 'Sennsor', 'Sensor -Narrow', 'Wide Band'};
            app.ProfileDropDown.Value = 'Imaging';
            app.Apply_RS = uibutton(app.Panel, 'push');
            app.Apply_RS.Text = 'Apply';
            app.Start_RS = uibutton(app.Panel, 'state');
            app.Start_RS.Text = 'Start';
            app.StopSave_RS = uibutton(app.Panel, 'state');
            app.StopSave_RS.Text = 'Stop-Save';
            app.AntennaPairsPanel = uipanel(app.GridLayout5);
            app.AntennaPairsPanel.Title = 'Antenna Pairs';
            app.Graph1DropDownLabel = uilabel(app.AntennaPairsPanel);
            app.Graph1DropDownLabel.Text = 'Graph 1:';
            app.Graph1DropDown = uidropdown(app.AntennaPairsPanel);
            app.Graph1DropDown.Items = {'1 → 2', '1 → 3', '1 → 4', '1 → 5', '1 → 6', '1 → 7', '1 → 8', '1 → 9', '2 → 3', '2 → 4', '2 → 5', '2 → 6', '2 → 7', '2 → 8', '2 → 9', '2 → 10', '3 → 4', '3 → 5', '3 → 6', '3 → 7', '3 → 8', '3 → 9', '3 → 10', '3 → 11', '4 → 5', '4 → 6', '4 → 7', '4 → 8', '4 → 9', '4 → 10', '4 → 11', '4 → 12', '5 → 6', '5 → 7', '5 → 8', '5 → 9', '5 → 10', '5 → 11', '5 → 12', '5 → 13', '6 → 7', '6 → 8', '6 → 9', '6 → 10', '6 → 11', '6 → 12', '6  → 13', '6 → 14', '7 → 8', '7 → 9', '7 → 10', '7 → 11', '7 → 12', '7 → 13', '7 → 14', '7 → 15', '8 → 9', '8 → 10', '8 → 11', '8 → 12', '8 → 13', '8 → 14', '8 → 15', '8 → 16', '9 → 10', '9 → 11', '9 → 12', '9 → 13', '9 → 14', '9 → 15', '9 → 16', '9 → 17', '10 → 11', '10 → 12', '10 → 13', '10 → 14', '10 → 15', '10 → 16', '10 → 17', '10 → 18', '10 → 1', '11 → 12', '11 → 13', '11 → 14', '11 → 15', '11 → 16', '11 → 17', '11 → 18', '11 → 1', '11 → 2', '12 → 13', '12 → 14', '12 → 15', '12 → 16', '12 → 17', '12 → 18', '12 → 1', '12 → 2', '12 → 3', '13 → 14', '13 → 15', '13 → 16', '13 → 17', '13 → 18', '13 →1', '13 → 2', '13 → 4', '14 → 15', '14 → 16', '14 → 17', '14 → 18', '14 → 1', '14 → 2', '14 → 3', '14 → 4', '14 → 5', '15 → 16', '15 → 17', '15 → 18', '15 → 1', '15 → 2', '15 → 3', '15 → 4', '15 → 5', '15 → 6', '16 → 17', '16 → 18', '16 → 1', '16 → 2', '16 → 3', '16 → 4', '16 → 5', '16 → 6', '16 → 7', '17 → 18', '17 → 1', '17 → 2', '17 → 3', '17 → 4', '17 → 5', '17 → 6', '17 → 7', '17 → 8', '18 → 1', '18 → 2', '18 → 3', '18 → 4', '18 → 5', '18 → 6', '18 → 7', '18 → 8', '18 → 9'};
            app.Graph1DropDown.Value = '1 → 2';
            app.Graph2DropDownLabel = uilabel(app.AntennaPairsPanel);
            app.Graph2DropDownLabel.Text = 'Graph 2:';
            app.Graph2DropDown = uidropdown(app.AntennaPairsPanel);
            app.Graph2DropDown.Items = {'1 → 2', '1 → 3', '1 → 4', '1 → 5', '1 → 6', '1 → 7', '1 → 8', '1 → 9', '2 → 3', '2 → 4', '2 → 5', '2 → 6', '2 → 7', '2 → 8', '2 → 9', '2 → 10', '3 → 4', '3 → 5', '3 → 6', '3 → 7', '3 → 8', '3 → 9', '3 → 10', '3 → 11', '4 → 5', '4 → 6', '4 → 7', '4 → 8', '4 → 9', '4 → 10', '4 → 11', '4 → 12', '5 → 6', '5 → 7', '5 → 8', '5 → 9', '5 → 10', '5 → 11', '5 → 12', '5 → 13', '6 → 7', '6 → 8', '6 → 9', '6 → 10', '6 → 11', '6 → 12', '6  → 13', '6 → 14', '7 → 8', '7 → 9', '7 → 10', '7 → 11', '7 → 12', '7 → 13', '7 → 14', '7 → 15', '8 → 9', '8 → 10', '8 → 11', '8 → 12', '8 → 13', '8 → 14', '8 → 15', '8 → 16', '9 → 10', '9 → 11', '9 → 12', '9 → 13', '9 → 14', '9 → 15', '9 → 16', '9 → 17', '10 → 11', '10 → 12', '10 → 13', '10 → 14', '10 → 15', '10 → 16', '10 → 17', '10 → 18', '10 → 1', '11 → 12', '11 → 13', '11 → 14', '11 → 15', '11 → 16', '11 → 17', '11 → 18', '11 → 1', '11 → 2', '12 → 13', '12 → 14', '12 → 15', '12 → 16', '12 → 17', '12 → 18', '12 → 1', '12 → 2', '12 → 3', '13 → 14', '13 → 15', '13 → 16', '13 → 17', '13 → 18', '13 →1', '13 → 2', '13 → 4', '14 → 15', '14 → 16', '14 → 17', '14 → 18', '14 → 1', '14 → 2', '14 → 3', '14 → 4', '14 → 5', '15 → 16', '15 → 17', '15 → 18', '15 → 1', '15 → 2', '15 → 3', '15 → 4', '15 → 5', '15 → 6', '16 → 17', '16 → 18', '16 → 1', '16 → 2', '16 → 3', '16 → 4', '16 → 5', '16 → 6', '16 → 7', '17 → 18', '17 → 1', '17 → 2', '17 → 3', '17 → 4', '17 → 5', '17 → 6', '17 → 7', '17 → 8', '18 → 1', '18 → 2', '18 → 3', '18 → 4', '18 → 5', '18 → 6', '18 → 7', '18 → 8', '18 → 9'};
            app.Graph2DropDown.Value = '1 → 2';
            app.Graph3DropDownLabel = uilabel(app.AntennaPairsPanel);
            app.Graph3DropDownLabel.Text = 'Graph 3:';
            app.Graph3DropDown = uidropdown(app.AntennaPairsPanel);
            app.Graph3DropDown.Items = {'1 → 2', '1 → 3', '1 → 4', '1 → 5', '1 → 6', '1 → 7', '1 → 8', '1 → 9', '2 → 3', '2 → 4', '2 → 5', '2 → 6', '2 → 7', '2 → 8', '2 → 9', '2 → 10', '3 → 4', '3 → 5', '3 → 6', '3 → 7', '3 → 8', '3 → 9', '3 → 10', '3 → 11', '4 → 5', '4 → 6', '4 → 7', '4 → 8', '4 → 9', '4 → 10', '4 → 11', '4 → 12', '5 → 6', '5 → 7', '5 → 8', '5 → 9', '5 → 10', '5 → 11', '5 → 12', '5 → 13', '6 → 7', '6 → 8', '6 → 9', '6 → 10', '6 → 11', '6 → 12', '6  → 13', '6 → 14', '7 → 8', '7 → 9', '7 → 10', '7 → 11', '7 → 12', '7 → 13', '7 → 14', '7 → 15', '8 → 9', '8 → 10', '8 → 11', '8 → 12', '8 → 13', '8 → 14', '8 → 15', '8 → 16', '9 → 10', '9 → 11', '9 → 12', '9 → 13', '9 → 14', '9 → 15', '9 → 16', '9 → 17', '10 → 11', '10 → 12', '10 → 13', '10 → 14', '10 → 15', '10 → 16', '10 → 17', '10 → 18', '10 → 1', '11 → 12', '11 → 13', '11 → 14', '11 → 15', '11 → 16', '11 → 17', '11 → 18', '11 → 1', '11 → 2', '12 → 13', '12 → 14', '12 → 15', '12 → 16', '12 → 17', '12 → 18', '12 → 1', '12 → 2', '12 → 3', '13 → 14', '13 → 15', '13 → 16', '13 → 17', '13 → 18', '13 →1', '13 → 2', '13 → 4', '14 → 15', '14 → 16', '14 → 17', '14 → 18', '14 → 1', '14 → 2', '14 → 3', '14 → 4', '14 → 5', '15 → 16', '15 → 17', '15 → 18', '15 → 1', '15 → 2', '15 → 3', '15 → 4', '15 → 5', '15 → 6', '16 → 17', '16 → 18', '16 → 1', '16 → 2', '16 → 3', '16 → 4', '16 → 5', '16 → 6', '16 → 7', '17 → 18', '17 → 1', '17 → 2', '17 → 3', '17 → 4', '17 → 5', '17 → 6', '17 → 7', '17 → 8', '18 → 1', '18 → 2', '18 → 3', '18 → 4', '18 → 5', '18 → 6', '18 → 7', '18 → 8', '18 → 9'};
            app.Graph3DropDown.Value = '1 → 2';
            app.Graph4DropDownLabel = uilabel(app.AntennaPairsPanel);
            app.Graph4DropDownLabel.Text = 'Graph 4:';
            app.Graph4DropDown = uidropdown(app.AntennaPairsPanel);
            app.Graph4DropDown.Items = {'1 → 2', '1 → 3', '1 → 4', '1 → 5', '1 → 6', '1 → 7', '1 → 8', '1 → 9', '2 → 3', '2 → 4', '2 → 5', '2 → 6', '2 → 7', '2 → 8', '2 → 9', '2 → 10', '3 → 4', '3 → 5', '3 → 6', '3 → 7', '3 → 8', '3 → 9', '3 → 10', '3 → 11', '4 → 5', '4 → 6', '4 → 7', '4 → 8', '4 → 9', '4 → 10', '4 → 11', '4 → 12', '5 → 6', '5 → 7', '5 → 8', '5 → 9', '5 → 10', '5 → 11', '5 → 12', '5 → 13', '6 → 7', '6 → 8', '6 → 9', '6 → 10', '6 → 11', '6 → 12', '6  → 13', '6 → 14', '7 → 8', '7 → 9', '7 → 10', '7 → 11', '7 → 12', '7 → 13', '7 → 14', '7 → 15', '8 → 9', '8 → 10', '8 → 11', '8 → 12', '8 → 13', '8 → 14', '8 → 15', '8 → 16', '9 → 10', '9 → 11', '9 → 12', '9 → 13', '9 → 14', '9 → 15', '9 → 16', '9 → 17', '10 → 11', '10 → 12', '10 → 13', '10 → 14', '10 → 15', '10 → 16', '10 → 17', '10 → 18', '10 → 1', '11 → 12', '11 → 13', '11 → 14', '11 → 15', '11 → 16', '11 → 17', '11 → 18', '11 → 1', '11 → 2', '12 → 13', '12 → 14', '12 → 15', '12 → 16', '12 → 17', '12 → 18', '12 → 1', '12 → 2', '12 → 3', '13 → 14', '13 → 15', '13 → 16', '13 → 17', '13 → 18', '13 →1', '13 → 2', '13 → 4', '14 → 15', '14 → 16', '14 → 17', '14 → 18', '14 → 1', '14 → 2', '14 → 3', '14 → 4', '14 → 5', '15 → 16', '15 → 17', '15 → 18', '15 → 1', '15 → 2', '15 → 3', '15 → 4', '15 → 5', '15 → 6', '16 → 17', '16 → 18', '16 → 1', '16 → 2', '16 → 3', '16 → 4', '16 → 5', '16 → 6', '16 → 7', '17 → 18', '17 → 1', '17 → 2', '17 → 3', '17 → 4', '17 → 5', '17 → 6', '17 → 7', '17 → 8', '18 → 1', '18 → 2', '18 → 3', '18 → 4', '18 → 5', '18 → 6', '18 → 7', '18 → 8', '18 → 9'};
            app.Graph4DropDown.Value = '1 → 2';
            app.Panel_8 = uipanel(app.GridLayout5);
            app.GridLayout11 = uigridlayout(app.Panel_8);
            app.ConnectionStatusLamp_6Label = uilabel(app.GridLayout11);
            app.ConnectionStatusLamp_6Label.Text = 'Connection Status:';
            app.ConnectionStatusLamp_RS = uilamp(app.GridLayout11);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = walabotUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end